classdef pv_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NumberofbreathsEditField       matlab.ui.control.NumericEditField
        NumberofbreathsEditFieldLabel  matlab.ui.control.Label
        UsertoenterLabel               matlab.ui.control.Label
        triangleP_awEditField          matlab.ui.control.NumericEditField
        triangleP_awEditFieldLabel     matlab.ui.control.Label
        P_muspeakEditField             matlab.ui.control.NumericEditField
        P_muspeakEditFieldLabel        matlab.ui.control.Label
        RunSimulationButton            matlab.ui.control.Button
        Q_cycleEditField               matlab.ui.control.NumericEditField
        Q_cycleEditFieldLabel          matlab.ui.control.Label
        T_inspriseEditField            matlab.ui.control.NumericEditField
        T_inspriseEditFieldLabel       matlab.ui.control.Label
        PS_upperEditField              matlab.ui.control.NumericEditField
        PS_upperEditFieldLabel         matlab.ui.control.Label
        PEEPEditField                  matlab.ui.control.NumericEditField
        PEEPEditFieldLabel             matlab.ui.control.Label
        VentilatorLabel                matlab.ui.control.Label
        PatientLabel                   matlab.ui.control.Label
        ElastanceEditField             matlab.ui.control.NumericEditField
        ElastanceEditFieldLabel        matlab.ui.control.Label
        ResistanceEditField            matlab.ui.control.NumericEditField
        ResistanceEditFieldLabel       matlab.ui.control.Label
        PatientDropDown                matlab.ui.control.DropDown
        PatientDropDownLabel           matlab.ui.control.Label
        UIAxes_pmus                    matlab.ui.control.UIAxes
        UIAxes_vol                     matlab.ui.control.UIAxes
        UIAxes_flow                    matlab.ui.control.UIAxes
        UIAxes_paw                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        ip % table, patient mechanics, ventilator settings, pv interactions
        m_k
        int_k
        m_tau
        int_tau
        m_t_insp
        int_t_insp
        pmus_model
        T_onset
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ip = readtable("ip_app.xlsx");
        end

        % Value changed function: PatientDropDown
        function PatientDropDownValueChanged(app, event)
            value = app.PatientDropDown.Value;

            % Convert numeric dropdown value to number if needed
            if isnumeric(value)
                valNum = value;
            else
                valNum = str2double(value);
            end

            % Find the row where Patient matches selection
            idx = app.ip.pat == valNum;

            % Populate field if found
            if any(idx)
                app.ResistanceEditField.Value = app.ip.R(idx);
                app.ElastanceEditField.Value = app.ip.E(idx);
                app.PEEPEditField.Value = app.ip.PEEP(idx);
                app.PS_upperEditField.Value = app.ip.ps_upper(idx);
                app.T_inspriseEditField.Value = app.ip.t_insp_rise(idx);
                app.Q_cycleEditField.Value = app.ip.Q_cycle(idx);
                app.triangleP_awEditField.Value = app.ip.triangle_paw(idx);

                %PV interaction relationships (fixed)
                app.m_k = app.ip.m_k(idx);
                app.int_k = app.ip.int_k(idx);
                app.m_tau = app.ip.m_tau(idx);
                app.int_tau = app.ip.int_tau(idx);
                app.m_t_insp = app.ip.m_t_insp(idx);
                app.int_t_insp = app.ip.int_t_insp(idx);

                %pmus general model
                % pmus_model_struct =
                general_model = load("general_pmus_model\"+string(app.ip.file_general_model(idx)),'pmus_model');
                app.pmus_model = general_model.pmus_model;
                app.T_onset = app.ip.T_onset(idx);
            else
                uialert(app.UIFigure, sprintf('No entry found for patient %s.', value), 'Data Error');
            end

        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            %Patient mechanics
            R = app.ResistanceEditField.Value;
            E = app.ElastanceEditField.Value;
            pmus_peak = app.P_muspeakEditField.Value;

            %Ventilator settings
            PEEP = app.PEEPEditField.Value;
            ps_upper = app.PS_upperEditField.Value;
            t_insp_rise = app.T_inspriseEditField.Value;
            q_cycle = app.Q_cycleEditField.Value;
            triangle_paw_trig = app.triangleP_awEditField.Value;
            num_breaths = app.NumberofbreathsEditField.Value;

            %pmus general model
            shortest_breath = 150;
            %scale magnitude and duration
            pmus_model_scaled = pmus_peak*app.pmus_model;
            Tinsp = app.m_t_insp*pmus_peak+app.int_t_insp + app.T_onset;
            stretch_factor = Tinsp/shortest_breath; %factor to stretch/compress effort
            [P, Q] = rat(stretch_factor, 1e-3); %determine ratio using integer num + den values
            pmus_scaled_stretched = resample(pmus_model_scaled, P, Q);   %resample to adjust duration

            Pmus = zeros(1,100); %initial padding

            %ensure length of breath (insp + exp) the same for each breath
            if length(pmus_scaled_stretched) >= 700
                pmus_scaled_stretched =  pmus_scaled_stretched(1:700);
            else
                pmus_scaled_stretched = [pmus_scaled_stretched, zeros(1,700-length(pmus_scaled_stretched))];
            end

            for i =1:num_breaths
                Pmus = [Pmus pmus_scaled_stretched];
            end

            t = 0:0.01:length(Pmus)/100-0.01;
            pmus_in = timeseries(Pmus,t);

            % Prepare variables for Simulink workspace
            assignin('base', 'R', R/1000);
            assignin('base', 'E', E/1000);
            assignin('base', 'pmus_peak', pmus_peak);
            assignin('base', 'PEEP', PEEP);
            assignin('base', 'ps_upper', ps_upper);
            assignin('base', 't_insp_rise', t_insp_rise);
            assignin('base', 'q_cycle', q_cycle);
            assignin('base', 'triangle_paw_trig', triangle_paw_trig);
            assignin('base', 'm_k', app.m_k);
            assignin('base', 'int_k', app.int_k);
            assignin('base', 'm_tau', app.m_tau);
            assignin('base', 'int_tau', app.int_tau);
            assignin('base','simTime', t(end));
            assignin('base','pmus_in', pmus_in);

            %run model
            simOut = sim('pv_interactions', 'ReturnWorkspaceOutputs', 'on');

            %extract signals
            paw = simOut.paw.data;
            ps_target = simOut.ps_target.data;
            flow = simOut.flow.data;
            volume = simOut.vol.data;
            pmus_out = squeeze(simOut.pmus.data);
            T_sim = simOut.tout;
            
            %clear old plots
            cla(app.UIAxes_pmus);
            cla(app.UIAxes_paw);
            cla(app.UIAxes_flow);
            cla(app.UIAxes_vol);

            % plot results
            %pmus
            plot(app.UIAxes_pmus, T_sim,pmus_out, 'color', [0.4660, 0.6740, 0.1880], 'LineWidth', 2);
            %paw + ps_target
            plot(app.UIAxes_paw, T_sim,paw,'color', [0.8500, 0.3250, 0.0980], 'LineWidth', 2);
            hold(app.UIAxes_paw,'on')
            plot(app.UIAxes_paw, T_sim,ps_target, 'color', [0.8500, 0.3250, 0.0980], 'LineWidth', 1, 'LineStyle',':')
            hold(app.UIAxes_paw, 'off')
            %flow
            plot(app.UIAxes_flow, T_sim, flow, 'color', [0, 0.4470, 0.7410], 'LineWidth', 2);
            %volume
            plot(app.UIAxes_vol, T_sim, volume, 'color',[0.4940, 0.1840, 0.5560], 'LineWidth', 2);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_paw = uiaxes(app.UIFigure);
            title(app.UIAxes_paw, 'Airway Pressure')
            xlabel(app.UIAxes_paw, 'Time [s]')
            ylabel(app.UIAxes_paw, 'cmH2O')
            zlabel(app.UIAxes_paw, 'Z')
            app.UIAxes_flow = uiaxes(app.UIFigure);
            title(app.UIAxes_flow, 'Airway Flow')
            xlabel(app.UIAxes_flow, 'Time [s]')
            ylabel(app.UIAxes_flow, 'mL/s')
            zlabel(app.UIAxes_flow, 'Z')
            app.UIAxes_vol = uiaxes(app.UIFigure);
            title(app.UIAxes_vol, 'Tidal Volume')
            xlabel(app.UIAxes_vol, 'Time [s]')
            ylabel(app.UIAxes_vol, 'mL')
            zlabel(app.UIAxes_vol, 'Z')
            app.UIAxes_pmus = uiaxes(app.UIFigure);
            title(app.UIAxes_pmus, 'Muscle Pressure')
            xlabel(app.UIAxes_pmus, 'Time [s]')
            ylabel(app.UIAxes_pmus, 'cmH2O')
            zlabel(app.UIAxes_pmus, 'Z')
            app.PatientDropDownLabel = uilabel(app.UIFigure);
            app.PatientDropDownLabel.Text = 'Patient';
            app.PatientDropDown = uidropdown(app.UIFigure);
            app.PatientDropDown.Items = {'select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.PatientDropDown.ValueChangedFcn = createCallbackFcn(app, @PatientDropDownValueChanged, true);
            app.PatientDropDown.Value = 'select';
            app.ResistanceEditFieldLabel = uilabel(app.UIFigure);
            app.ResistanceEditFieldLabel.Text = 'Resistance';
            app.ResistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElastanceEditFieldLabel = uilabel(app.UIFigure);
            app.ElastanceEditFieldLabel.Text = 'Elastance';
            app.ElastanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.PatientLabel = uilabel(app.UIFigure);
            app.PatientLabel.Text = 'Patient ';
            app.VentilatorLabel = uilabel(app.UIFigure);
            app.VentilatorLabel.Text = 'Ventilator';
            app.PEEPEditFieldLabel = uilabel(app.UIFigure);
            app.PEEPEditFieldLabel.Text = 'PEEP';
            app.PEEPEditField = uieditfield(app.UIFigure, 'numeric');
            app.PS_upperEditFieldLabel = uilabel(app.UIFigure);
            app.PS_upperEditFieldLabel.Interpreter = 'latex';
            app.PS_upperEditFieldLabel.Text = '$PS_{upper}$';
            app.PS_upperEditField = uieditfield(app.UIFigure, 'numeric');
            app.T_inspriseEditFieldLabel = uilabel(app.UIFigure);
            app.T_inspriseEditFieldLabel.Interpreter = 'latex';
            app.T_inspriseEditFieldLabel.Text = '$T_{insp, \: rise}$';
            app.T_inspriseEditField = uieditfield(app.UIFigure, 'numeric');
            app.Q_cycleEditFieldLabel = uilabel(app.UIFigure);
            app.Q_cycleEditFieldLabel.Interpreter = 'latex';
            app.Q_cycleEditFieldLabel.Text = '$\% Q_{cycle}$';
            app.Q_cycleEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.P_muspeakEditFieldLabel = uilabel(app.UIFigure);
            app.P_muspeakEditFieldLabel.Interpreter = 'latex';
            app.P_muspeakEditFieldLabel.Text = '$P_{mus, \: peak}$';
            app.P_muspeakEditField = uieditfield(app.UIFigure, 'numeric');
            app.P_muspeakEditField.Limits = [0 Inf];
            app.triangleP_awEditFieldLabel = uilabel(app.UIFigure);
            app.triangleP_awEditFieldLabel.Interpreter = 'latex';
            app.triangleP_awEditFieldLabel.Text = '$\triangle P_{aw}$';
            app.triangleP_awEditField = uieditfield(app.UIFigure, 'numeric');
            app.UsertoenterLabel = uilabel(app.UIFigure);
            app.UsertoenterLabel.Text = 'User to enter:';
            app.NumberofbreathsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofbreathsEditFieldLabel.Interpreter = 'latex';
            app.NumberofbreathsEditFieldLabel.Text = 'Number of breaths';
            app.NumberofbreathsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofbreathsEditField.Limits = [0 Inf];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pv_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end